classdef Drone817 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataCollectionModeSwitch       matlab.ui.control.Switch
        DataCollectionModeSwitchLabel  matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UAUV                           matlab.ui.control.UIAxes
        UAMoi                          matlab.ui.control.UIAxes
        UAHum                          matlab.ui.control.UIAxes
        UATemp                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bt % bluetooth device
        data % Data gathered from device
        terminator %terminate data gathering
        i
        C
        Temp %col 3
        Moi  %col 1
        Hum  %col 2
        UV   %col 4
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           app.UAUV.Title.String = 'UV'
           app.UAHum.Title.String = 'Humidity'
           app.UAMoi.Title.String = 'Moisture'
           app.UATemp.Title.String = 'Temperature'
           app.UAHum.YLabel.String = 'Humidity %'
           app.UATemp.YLabel.String = 'Temperature (C)'
           app.UAMoi.YLabel.String = 'Moisture %'
           app.UAUV.YLabel.String = 'UV light index'
           app.UAHum.XLabel.String = 'Data Point #'
           app.UAUV.XLabel.String = 'Data Point #'
           app.UATemp.XLabel.String = 'Data Point #'
           app.UAMoi.XLabel.String = 'Data Point #'

            fprintf(app.bt,'1')
            app.i=1;
            %
            app.C = []
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %readline(app.bt)
            app.C(end,:) = []
            fprintf(app.bt,'3')
            app.i=3;
            disp(app.C)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.bt=bluetooth("DSD TECH HC-05",1);
           
        end

        % Callback function
        function InformationTextAreaValueChanging(app, event)
            changingValue = event.Value;
            if app.i==1
                while app.i==1
                fscanf(app.bt)
                end
            end 
        end

        % Value changed function: DataCollectionModeSwitch
        function DataCollectionModeSwitchValueChanged(app, event)
            value = app.DataCollectionModeSwitch.Value;
              
             if value == 'On'
                 for n = 1:1000000                    
                     fscanf(app.bt)
                     assignin('base','ans',ans)
                     str2num(ans)
                     app.C = vertcat(app.C,ans)
                     disp(app.C)
                     app.UV = app.C(:,4);
                     app.Hum = app.C(:,2);
                     app.Temp = app.C(:,3);
                     app.Moi = app.C(:,1);
                     plot(app.UAHum,[app.Hum])
                     plot(app.UAMoi,[app.Moi])
                     plot(app.UAUV,[app.UV])
                     plot(app.UATemp,[app.Temp])
                 end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UATemp = uiaxes(app.UIFigure);
            title(app.UATemp, 'Title')
            xlabel(app.UATemp, 'X')
            ylabel(app.UATemp, 'Y')
            zlabel(app.UATemp, 'Z')
            app.UAHum = uiaxes(app.UIFigure);
            title(app.UAHum, 'Title')
            xlabel(app.UAHum, 'X')
            ylabel(app.UAHum, 'Y')
            zlabel(app.UAHum, 'Z')
            app.UAMoi = uiaxes(app.UIFigure);
            title(app.UAMoi, 'Title')
            xlabel(app.UAMoi, 'X')
            ylabel(app.UAMoi, 'Y')
            zlabel(app.UAMoi, 'Z')
            app.UAUV = uiaxes(app.UIFigure);
            title(app.UAUV, 'Title')
            xlabel(app.UAUV, 'X')
            ylabel(app.UAUV, 'Y')
            zlabel(app.UAUV, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DataCollectionModeSwitchLabel = uilabel(app.UIFigure);
            app.DataCollectionModeSwitchLabel.Text = 'Data Collection Mode';
            app.DataCollectionModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.DataCollectionModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DataCollectionModeSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drone817

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end